classdef GUI_AdaProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        STARTButton                     matlab.ui.control.Button
        Application                     matlab.ui.container.ButtonGroup
        SegmentationButton              matlab.ui.control.RadioButton
        CEC2014FunctionsButton          matlab.ui.control.RadioButton
        GeneralInitializationPanel      matlab.ui.container.Panel
        InputDimensionEditFieldLabel    matlab.ui.control.Label
        InputDimensionEditField         matlab.ui.control.NumericEditField
        InputLowerBoundEditFieldLabel   matlab.ui.control.Label
        InputLowerBoundEditField        matlab.ui.control.NumericEditField
        InputUpperBoundEditFieldLabel   matlab.ui.control.Label
        InputUpperBoundEditField        matlab.ui.control.NumericEditField
        NumberofIterationsLabel         matlab.ui.control.Label
        NumberofIterationsEditField     matlab.ui.control.NumericEditField
        PopulationSizeLabel             matlab.ui.control.Label
        PopulationSizeEditField         matlab.ui.control.NumericEditField
        CEC2014InitializationPanel      matlab.ui.container.Panel
        FunctionSelectionEditFieldLabel  matlab.ui.control.Label
        FunctionSelectionEditField      matlab.ui.control.NumericEditField
        MonteCarloRunsEditFieldLabel    matlab.ui.control.Label
        MonteCarloRunsEditField         matlab.ui.control.NumericEditField
        SegmentationSelectedImagePanel  matlab.ui.container.Panel
        ImageName                       matlab.ui.control.EditField
        PlotFunction                    matlab.ui.control.UIAxes
        OptimizationMethodListBoxLabel  matlab.ui.control.Label
        OptimizationMethodListBox       matlab.ui.control.ListBox
        SurfHistogram                   matlab.ui.control.UIAxes
        Image                           matlab.ui.control.UIAxes
        Segmentation                    matlab.ui.control.UIAxes
        Colormap                        matlab.ui.control.UIAxes
        SegmentationResultsPanel        matlab.ui.container.Panel
        FSIMLabel                       matlab.ui.control.Label
        FSIM                            matlab.ui.control.EditField
        PSNRLabel                       matlab.ui.control.Label
        PSNR                            matlab.ui.control.EditField
        SSIMEditFieldLabel              matlab.ui.control.Label
        SSIM                            matlab.ui.control.EditField
    end

    
    properties (Access = public)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            
            cla(app.PlotFunction) 
            cla(app.SurfHistogram) 
            cla(app.Image) 
            cla(app.Segmentation) 
            cla(app.Colormap) 
            app.ImageName.Value = ' ';
            app.FSIM.Value = ' ';
            app.SSIM.Value = ' ';
            app.PSNR.Value = ' ';
            
            D = app.InputDimensionEditField.Value;
            Xmin = app.InputLowerBoundEditField.Value;
            Xmax = app.InputUpperBoundEditField.Value;
            pop_size = app.PopulationSizeEditField.Value;
            iter_max = app.NumberofIterationsEditField.Value;
            runs = app.MonteCarloRunsEditField.Value;
            
            number_of_methods = size(app.OptimizationMethodListBox.Value,2);
            Legend = cell(number_of_methods,1);        
            
            
            if app.SegmentationButton.Value == 1
                
                D = 2*D;
                
                [file,folder] = uigetfile({'*.png;*.jpg;*.tif;*.bmp', 'Image Files (*.png,*.jpg,*.tif,*.bmp)'},...
                          'Image Selector');                
                
                      
                app.ImageName.Value = file;
                I = imread(fullfile(folder, file));
                
                [r, c, d] = size(I);
                imshow(I, 'Parent',app.Image);
                
                if ~isempty(d)
                    I = rgb2gray(I);
                end
                

                noisyImage = imnoise(I,'gaussian',0,0.0015);  %imnoise(I,'gaussian',0,0.0015);
                
                [NM_I,~] = imnlmfilt(noisyImage);
                
                q = zeros(256, 256);
                
                for x = 1:r
                    for y = 1:c                
                        
                        i = I(x,y) + 1; j = NM_I(x,y) + 1;
                        
                        q(i,j) = q(i,j) + 1;
                        
                    end
                end
                
                p = q/(r*c);
                
                [x1,x2] = meshgrid(1:256,1:256);
                
                surf(app.SurfHistogram, x1, x2, p, 'edgecolor', 'none');
                
                cg_curve = [];
                
                if any(strcmp(app.OptimizationMethodListBox.Value,'SSA'))
               
                    [~,best_position,cg_curve] = SSA_segmentation(pop_size,iter_max,Xmin,Xmax,D,@f_segmentation,p);                  
                                        
                    th = best_position(1:D/2);
                    th = sort(th);
                    Iout= imageGRAY(I,th);
                    
                end
                
                if any(strcmp(app.OptimizationMethodListBox.Value,'EHSSA'))
                    
                    [~,best_position,cg_curve] = EHSSA_segmentation(pop_size,iter_max,Xmin,Xmax,D,@f_segmentation,p);  
                    
                    th = best_position(1:D/2);
                    th = sort(th);
                    Iout = imageGRAY(I,th);
                    
                end
                
                [SSIM_v, FSIM_v, PSNR_v] = performance_evaluation(I, Iout);
                
                app.SSIM.Value = num2str(SSIM_v);
                app.FSIM.Value = num2str(FSIM_v);
                app.PSNR.Value = num2str(PSNR_v);
                
                imshow(Iout, 'Parent',app.Segmentation);
                imshow(Iout, 'Parent',app.Colormap);
                colormap(app.Colormap, 'jet');
                semilogy(app.PlotFunction,cg_curve,'Color','r','LineWidth',2);
                
                
            else
                
                func_num = app.FunctionSelectionEditField.Value;
                fhd = str2func('cec14_func');
                
                function_directory = which('SSA_CEC');
                [folder, ~, ~] = fileparts(function_directory);
                cd(folder)
                
                
                cg_curve_SSA = []; cg_curve_EHSSA = []; cg_curve_PSO = [];
                
                if any(strcmp(app.OptimizationMethodListBox.Value,'SSA'))
                    f = waitbar(0,'SSA Monte Carlo');                    
                    for j = 1:runs
                        waitbar(j/runs);
                        [best_position_SSA(:,j),best_val_SSA(:,j),cg_curve_SSA(:,j)] = SSA_CEC(pop_size,iter_max,Xmin,Xmax,D,fhd,func_num);                    
                    end
                    delete(f)
                end
                    
                if any(strcmp(app.OptimizationMethodListBox.Value,'EHSSA'))
                    f = waitbar(0,'EHSSA Monte Carlo');
                    for j = 1:runs 
                        waitbar(j/runs);
                        [best_position_EHSSA(:,j),best_val_EHSSA(:,j),cg_curve_EHSSA(:,j)] = EHSSA_CEC(pop_size,iter_max,Xmin,Xmax,D,fhd,func_num);                    
                    end
                    delete(f)
                    
                end

                ind = 1;
                hold(app.PlotFunction, 'on');
                                
                if ~isempty(cg_curve_SSA)
                    semilogy(app.PlotFunction, mean(cg_curve_SSA,2),'Color','r','LineWidth',2, 'DisplayName','SSA');
                    Legend{ind} = 'SSA';  ind = ind + 1;
                end
                
                if ~isempty(cg_curve_EHSSA)
                    semilogy(app.PlotFunction, mean(cg_curve_EHSSA,2),'Color','b','LineWidth',2, 'DisplayName', 'EHSSA');
                    Legend{ind} = 'EHSSA';  ind = ind + 1;
                end

                
                legend(app.PlotFunction,Legend); 
                     
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.Application = uibuttongroup(app.UIFigure);
            app.Application.Title = 'Select Application';
            app.SegmentationButton = uiradiobutton(app.Application);
            app.SegmentationButton.Text = 'Segmentation';
            app.SegmentationButton.Value = true;
            app.CEC2014FunctionsButton = uiradiobutton(app.Application);
            app.CEC2014FunctionsButton.Text = 'CEC2014 Functions';
            app.GeneralInitializationPanel = uipanel(app.UIFigure);
            app.GeneralInitializationPanel.Title = 'General Initialization';
            app.InputDimensionEditFieldLabel = uilabel(app.GeneralInitializationPanel);
            app.InputDimensionEditFieldLabel.Text = 'Input Dimension:';
            app.InputDimensionEditField = uieditfield(app.GeneralInitializationPanel, 'numeric');
            app.InputDimensionEditField.Value = 30;
            app.InputLowerBoundEditFieldLabel = uilabel(app.GeneralInitializationPanel);
            app.InputLowerBoundEditFieldLabel.Text = 'Input Lower Bound:';
            app.InputLowerBoundEditField = uieditfield(app.GeneralInitializationPanel, 'numeric');
            app.InputLowerBoundEditField.Value = 1;
            app.InputUpperBoundEditFieldLabel = uilabel(app.GeneralInitializationPanel);
            app.InputUpperBoundEditFieldLabel.Text = 'Input Upper Bound:';
            app.InputUpperBoundEditField = uieditfield(app.GeneralInitializationPanel, 'numeric');
            app.InputUpperBoundEditField.Value = 256;
            app.NumberofIterationsLabel = uilabel(app.GeneralInitializationPanel);
            app.NumberofIterationsLabel.Text = 'Number of Iterations:';
            app.NumberofIterationsEditField = uieditfield(app.GeneralInitializationPanel, 'numeric');
            app.NumberofIterationsEditField.Value = 100;
            app.PopulationSizeLabel = uilabel(app.GeneralInitializationPanel);
            app.PopulationSizeLabel.Text = 'Population Size:';
            app.PopulationSizeEditField = uieditfield(app.GeneralInitializationPanel, 'numeric');
            app.PopulationSizeEditField.Value = 20;
            app.CEC2014InitializationPanel = uipanel(app.UIFigure);
            app.CEC2014InitializationPanel.Title = 'CEC2014 Initialization';
            app.FunctionSelectionEditFieldLabel = uilabel(app.CEC2014InitializationPanel);
            app.FunctionSelectionEditFieldLabel.Text = 'Function Selection:';
            app.FunctionSelectionEditField = uieditfield(app.CEC2014InitializationPanel, 'numeric');
            app.FunctionSelectionEditField.Value = 1;
            app.MonteCarloRunsEditFieldLabel = uilabel(app.CEC2014InitializationPanel);
            app.MonteCarloRunsEditFieldLabel.Text = 'Monte Carlo Runs:';
            app.MonteCarloRunsEditField = uieditfield(app.CEC2014InitializationPanel, 'numeric');
            app.MonteCarloRunsEditField.Value = 1;
            app.SegmentationSelectedImagePanel = uipanel(app.UIFigure);
            app.SegmentationSelectedImagePanel.Title = 'Segmentation Selected Image';
            app.ImageName = uieditfield(app.SegmentationSelectedImagePanel, 'text');
            app.PlotFunction = uiaxes(app.UIFigure);
            title(app.PlotFunction, 'Plot of the Function')
            xlabel(app.PlotFunction, 'Iterations')
            ylabel(app.PlotFunction, 'Function Value (log scale)')
            app.PlotFunction.YScale = 'log';
            app.OptimizationMethodListBoxLabel = uilabel(app.UIFigure);
            app.OptimizationMethodListBoxLabel.Text = 'Optimization Method';
            app.OptimizationMethodListBox = uilistbox(app.UIFigure);
            app.OptimizationMethodListBox.Items = {'EHSSA', 'SSA'};
            app.OptimizationMethodListBox.Multiselect = 'on';
            app.OptimizationMethodListBox.Value = {'EHSSA'};
            app.SurfHistogram = uiaxes(app.UIFigure);
            title(app.SurfHistogram, 'The Plot of the 2D Histogram of the Image')
            xlabel(app.SurfHistogram, '')
            ylabel(app.SurfHistogram, '')
            app.Image = uiaxes(app.UIFigure);
            title(app.Image, 'Image')
            xlabel(app.Image, '')
            ylabel(app.Image, '')
            app.Segmentation = uiaxes(app.UIFigure);
            title(app.Segmentation, 'Segmentation Result')
            xlabel(app.Segmentation, '')
            ylabel(app.Segmentation, '')
            app.Colormap = uiaxes(app.UIFigure);
            title(app.Colormap, 'Colormap Result')
            xlabel(app.Colormap, '')
            ylabel(app.Colormap, '')
            app.SegmentationResultsPanel = uipanel(app.UIFigure);
            app.SegmentationResultsPanel.Title = 'Segmentation Results';
            app.FSIMLabel = uilabel(app.SegmentationResultsPanel);
            app.FSIMLabel.Text = 'FSIM';
            app.FSIM = uieditfield(app.SegmentationResultsPanel, 'text');
            app.PSNRLabel = uilabel(app.SegmentationResultsPanel);
            app.PSNRLabel.Text = 'PSNR';
            app.PSNR = uieditfield(app.SegmentationResultsPanel, 'text');
            app.SSIMEditFieldLabel = uilabel(app.SegmentationResultsPanel);
            app.SSIMEditFieldLabel.Text = 'SSIM';
            app.SSIM = uieditfield(app.SegmentationResultsPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_AdaProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end